classdef SegmentationChoice < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SegmentationOptionsButtonGroup  matlab.ui.container.ButtonGroup
        eventrelatedButton              matlab.ui.control.RadioButton
        sequentialButton                matlab.ui.control.RadioButton
        SelectPCASegmentationLabel      matlab.ui.control.Label
        EventrelatedSegmentationPanel   matlab.ui.container.Panel
        LeftBoundEditFieldLabel         matlab.ui.control.Label
        LeftBoundEditField              matlab.ui.control.NumericEditField
        RightBoundEditFieldLabel        matlab.ui.control.Label
        RightBoundEditField             matlab.ui.control.NumericEditField
        SegmentBoundsrelativetomarkerinmsLabel  matlab.ui.control.Label
        RunPCAEvnt                      matlab.ui.control.Button
        ListBoxLabel                    matlab.ui.control.Label
        ChanList                        matlab.ui.control.ListBox
        SequentialSegmentationPanel     matlab.ui.container.Panel
        SegmentwitdhinmsEditFieldLabel  matlab.ui.control.Label
        SegmentwitdhinmsEditField       matlab.ui.control.NumericEditField
        FirstsegmentstartinmsEditFieldLabel  matlab.ui.control.Label
        FirstsegmentstartinmsEditField  matlab.ui.control.NumericEditField
        RunPCASeq                       matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp % First parameter of the startup-function: The app which calls SegementationChoice
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            
            app.SequentialSegmentationPanel.Position = app.EventrelatedSegmentationPanel.Position;
            app.EventrelatedSegmentationPanel.Visible = 'on';
            app.SequentialSegmentationPanel.Visible = 'off';
            
            app.CallingApp = mainApp;
%             for i = 1:length(mainApp.Signal.event.type)
%                 if(mainApp.Signal.event.latency(i) < mainApp.Signal.srate)
%                     possibleEvents = possibleEvents{1:i i:};
%                 end
%             end
            app.ChanList.Items = unique({mainApp.Signal.event.type});
            app.ChanList.ItemsData = unique({mainApp.Signal.event.type});
        end

        % Selection changed function: SegmentationOptionsButtonGroup
        function SegmentationOptionsButtonGroupSelectionChanged(app, event)
            %Show only one segmentation method at the same time.
            selectedButton = app.SegmentationOptionsButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text, "sequential")
                app.EventrelatedSegmentationPanel.Visible = 'off';
                app.SequentialSegmentationPanel.Visible = 'on';
            else
                app.SequentialSegmentationPanel.Visible = 'off';
                app.EventrelatedSegmentationPanel.Visible = 'on';
            end
        end

        % Button pushed function: RunPCASeq
        function RunPCASeqButtonPushed(app, event)
            %Pass the options for sequential segmentation (offset and width) to the calling app, plot the signal and close.
            app.CallingApp.Segm.type = "sequential";
            
            app.CallingApp.Segm.width = int32(abs(app.CallingApp.Signal.srate/1000 * app.SegmentwitdhinmsEditField.Value));
            app.CallingApp.Segm.initOffset = int32(abs(app.CallingApp.Signal.srate/1000 * app.FirstsegmentstartinmsEditField.Value));
            
            app.CallingApp.PleaseSelect.Visible = 'off';
            
            app.CallingApp.Signal = EEGPCA(app.CallingApp.Signal, app.CallingApp.Segm);
            
            app.CallingApp.nChannel = 1;
            app.CallingApp.SegmentNr.Value = 0;
            app.CallingApp.setPca(app.CallingApp.Signal);
            app.CallingApp.updateUI(app.CallingApp.Signal);    
            
            %The data is now provided: Enable interface elements.
            app.CallingApp.AverageSignalButton.Enable = 'on';
            app.CallingApp.SegmentNr.Enable = 'on';
            app.CallingApp.RandomRealSignalButton.Enable = 'on';
            app.CallingApp.ShowFactorsCheckBox.Enable = 'on';
            app.CallingApp.ComparetoRealSignalCheckBox.Enable = 'on';
            
            UIFigureCloseRequest(app, event)
        end

        % Button pushed function: RunPCAEvnt
        function RunPCAEvntButtonPushed(app, event)
            %Pass the options for sequential segmentation (markers and bounds) to the calling app, plot the signal and close.
            %Selecting no markers, or only markers, which are less than 250 datapoints from signal beginning is disallowed.
            if(~isempty(app.ChanList.Value))
                breakmore = 0;
                for i = 1:length(app.CallingApp.Signal.event)
                    for j = 1:length(app.ChanList.Value)
                        if(strcmp(app.CallingApp.Signal.event(i).type, app.ChanList.Value(j)))
                            if(app.CallingApp.Signal.event(i).latency > 250)
                                breakmore = 1;
                                app.CallingApp.Segm.type = "eventRelated";
                                app.CallingApp.Segm.marker = app.ChanList.Value;
                                app.CallingApp.Segm.lBound = int32(abs(app.CallingApp.Signal.srate/1000 * app.LeftBoundEditField.Value));
                                app.CallingApp.Segm.rBound = int32(abs(app.CallingApp.Signal.srate/1000 * app.RightBoundEditField.Value));
                                
                                app.CallingApp.PleaseSelect.Visible = 'off';
                                
                                app.CallingApp.Signal = EEGPCA(app.CallingApp.Signal, app.CallingApp.Segm);
                                
                                app.CallingApp.nChannel = 1;
                                   app.CallingApp.SegmentNr.Value = 0;
                                app.CallingApp.setPca(app.CallingApp.Signal);
                                app.CallingApp.updateUI(app.CallingApp.Signal);
                                
                                %The data is now provided: Enable interface elements.
                                app.CallingApp.AverageSignalButton.Enable = 'on';
                                app.CallingApp.SegmentNr.Enable = 'on';
                                app.CallingApp.RandomRealSignalButton.Enable = 'on';
                                app.CallingApp.ShowFactorsCheckBox.Enable = 'on';
                                app.CallingApp.ComparetoRealSignalCheckBox.Enable = 'on';
                                
                                UIFigureCloseRequest(app, event)
                                break
                            end
                        end
                    end
                    if(breakmore)
                        break
                    end
                end
                if(~breakmore)
                    errordlg("All selected markers are too close to signal bounds!");
                end
            else
                errordlg("Please select at least one marker!");
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.BrowseFile.Enable = 'on';
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SegmentationOptionsButtonGroup = uibuttongroup(app.UIFigure);
            app.SegmentationOptionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SegmentationOptionsButtonGroupSelectionChanged, true);
            app.SegmentationOptionsButtonGroup.Title = 'Segmentation Options';
            app.eventrelatedButton = uiradiobutton(app.SegmentationOptionsButtonGroup);
            app.eventrelatedButton.Text = 'event related';
            app.eventrelatedButton.Value = true;
            app.sequentialButton = uiradiobutton(app.SegmentationOptionsButtonGroup);
            app.sequentialButton.Text = 'sequential';
            app.SelectPCASegmentationLabel = uilabel(app.UIFigure);
            app.SelectPCASegmentationLabel.Text = 'Select PCA Segmentation';
            app.EventrelatedSegmentationPanel = uipanel(app.UIFigure);
            app.EventrelatedSegmentationPanel.Title = 'Event-related Segmentation';
            app.LeftBoundEditFieldLabel = uilabel(app.EventrelatedSegmentationPanel);
            app.LeftBoundEditFieldLabel.Text = 'Left Bound';
            app.LeftBoundEditField = uieditfield(app.EventrelatedSegmentationPanel, 'numeric');
            app.LeftBoundEditField.Value = 200;
            app.RightBoundEditFieldLabel = uilabel(app.EventrelatedSegmentationPanel);
            app.RightBoundEditFieldLabel.Text = 'Right Bound';
            app.RightBoundEditField = uieditfield(app.EventrelatedSegmentationPanel, 'numeric');
            app.RightBoundEditField.Value = 2500;
            app.SegmentBoundsrelativetomarkerinmsLabel = uilabel(app.EventrelatedSegmentationPanel);
            app.SegmentBoundsrelativetomarkerinmsLabel.Text = 'Segment Bounds relative to marker (in ms)';
            app.RunPCAEvnt = uibutton(app.EventrelatedSegmentationPanel, 'push');
            app.RunPCAEvnt.ButtonPushedFcn = createCallbackFcn(app, @RunPCAEvntButtonPushed, true);
            app.RunPCAEvnt.Text = 'Run PCA';
            app.ListBoxLabel = uilabel(app.EventrelatedSegmentationPanel);
            app.ListBoxLabel.Text = 'List Box';
            app.ChanList = uilistbox(app.EventrelatedSegmentationPanel);
            app.ChanList.Items = {};
            app.ChanList.Multiselect = 'on';
            app.ChanList.Value = {};
            app.SequentialSegmentationPanel = uipanel(app.UIFigure);
            app.SequentialSegmentationPanel.Title = 'Sequential Segmentation';
            app.SegmentwitdhinmsEditFieldLabel = uilabel(app.SequentialSegmentationPanel);
            app.SegmentwitdhinmsEditFieldLabel.Text = 'Segment witdh (in ms)';
            app.SegmentwitdhinmsEditField = uieditfield(app.SequentialSegmentationPanel, 'numeric');
            app.SegmentwitdhinmsEditField.Value = 1000;
            app.FirstsegmentstartinmsEditFieldLabel = uilabel(app.SequentialSegmentationPanel);
            app.FirstsegmentstartinmsEditFieldLabel.Text = 'First segment start (in ms)';
            app.FirstsegmentstartinmsEditField = uieditfield(app.SequentialSegmentationPanel, 'numeric');
            app.FirstsegmentstartinmsEditField.Value = 1000;
            app.RunPCASeq = uibutton(app.SequentialSegmentationPanel, 'push');
            app.RunPCASeq.ButtonPushedFcn = createCallbackFcn(app, @RunPCASeqButtonPushed, true);
            app.RunPCASeq.Text = 'Run PCA';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SegmentationChoice(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end