classdef SingleEEGPCA1_3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        BrowseFile                   matlab.ui.container.Menu
        UIAxes                       matlab.ui.control.UIAxes
        AverageSignalButton          matlab.ui.control.Button
        ShowFactorsCheckBox          matlab.ui.control.CheckBox
        ComparetoRealSignalCheckBox  matlab.ui.control.CheckBox
        DisplaySegmentNrLabel        matlab.ui.control.Label
        SegmentNr                    matlab.ui.control.NumericEditField
        RandomRealSignalButton       matlab.ui.control.Button
        PCAFactorsPanel              matlab.ui.container.Panel
        ChannelDropDownLabel         matlab.ui.control.Label
        ChannelSelect                matlab.ui.control.DropDown
        PleaseSelect                 matlab.ui.control.Label
        SliderLabel                  matlab.ui.control.Label
        Slider                       matlab.ui.control.Slider
    end

    %   Author: Anton Whittaker, written 22.03.2019 as part of the course "Programming for Psychologists" at the University of Groningen. Supervisor: Mark Span.
    %
    %   This app is designed to take an EEG signal file (header, markers and data), which is formatted according to the Brainvision Analyser data format.
    %   It then partitions this signal into segments, either subsequent signals of the same width, or segments denoted by markers and conducts a Principal
    %   Component Analysis (PCA) for each channel separately. The interface then displays these different segments graphically alongside the respective PCA
    %   factors, which can be manipulated through sliders. There are options to show the first 5 components of the plotted signal as well as the orignial graphically.
    %   This application is primarily designed for educational use to illustrate the method of PCA as well as fundamental components of EEG signals.
    %
    %   Dependencies: eeglab7_1_3_13b, EEGPCA, SegmentationChoice
    %   eeglab might throw an exception using pop_loadbva. In this case, change eeglab7_1_3_13b\help\supergui.m
    %   line 267    'fig'       'real'   []      [];
    %   to          'fig'       ''   []      [];
    %   This application was written for MATLAB 2018b, compability with other versions is not guaranteed.


    properties (Access = public)
        
        Segm        %Segmentation Options (see SegmentationChoice app)
        
        Signal      %EEG signal following an extended version of the EEGlab structure
        PlotSig     %Multichannel EEG-Segment to be plotted.
        
        SegmChoice  %This app may be called to choose segmentation options
        Factors     %PCA Factors
        
        MAXFACT     %Maximum number of PCA factors
        
        nChannel    %Links the name of a channel to the index of the corresponding data.
    end


    methods (Access = public)
        function updateUI(app, Signal)
            for i = 1:app.MAXFACT
                %Update the label
                
                label = app.Factors(i).label;
                label.HorizontalAlignment = 'right';
                label.Position = [11 (569 - 42 * (i - 1)) 46 22];
                label.Text = strcat('Fac ', num2str(i));
                
                % Update the Slider
                slider = app.Factors(i).slider;
                slider.MajorTickLabels = {'min', 'max'};
                slider.MinorTicks = [];
                slider.Position = [73 (578 - 42 * (i - 1)) 271 3];
                for j = 1:Signal.nbchan
                    if(strcmp(app.ChannelSelect.Value, app.Signal.channelName{j}))
                        if(app.SegmentNr.Value)
                            slider.Limits = [min(Signal.score(j, :, i))*2 max(Signal.score(j, :, i))*2];
                            slider.MajorTicks = [min(Signal.score(j, :, i))*2 max(Signal.score(j, :, i))*2];
                            slider.Value = Signal.score(j, app.SegmentNr.Value, i);
                            break;
                        else
                            slider.Limits = [min(min(Signal.score(j,:,:)))*2 max(max(Signal.score(j,:,:)))*2];
                            slider.MajorTicks = [min(min(Signal.score(j,:,:)))*2 max(max(Signal.score(j,:,:)))*2];
                            slider.Value = 0;
                            break
                        end
                    end
                end
                
                
            end
            app.SegmentNr.Limits = [0 Signal.nbseg];
            app.PCAFactorsPanel.Visible = 'on';
        end
        
        
        function setPca(app, Signal)
            %Plots a segment of the selected channel and sets channel/signal-selections accordingly
            %Setting channel 0 will plot the arithmetic mean of all segments.
            results.channels = Signal.channelName;
            app.ChannelSelect.Items = results.channels;
            app.ChannelSelect.Value = results.channels(app.nChannel);
            results.av(app.nChannel, :) = mean(squeeze(Signal.data(app.nChannel, :, :)));
            results.data = results.av(app.nChannel, :);
            if (app.SegmentNr.Value > 0)
                for i = 1 :length(Signal.score(app.nChannel, app.SegmentNr.Value, :))
                    results.data = results.data + Signal.weights(app.nChannel, :, i) * Signal.score(app.nChannel, app.SegmentNr.Value, i);
                    if(i < 21)
                        app.Factors(i).slider.Limits = [min(Signal.score(app.nChannel, :, i))*2 max(Signal.score(app.nChannel, :, i))*2];
                        app.Factors(i).slider.MajorTicks = [min(Signal.score(app.nChannel, :, i))*2 max(Signal.score(app.nChannel, :, i))*2];
                        app.Factors(i).slider.Value = Signal.score(app.nChannel, app.SegmentNr.Value, i);
                    end
                end
            end
            app.PlotSig = results;
            app.plotAll(app.PlotSig.data);
            return
        end


    end


    methods (Access = private)
        %   This function will comupute the PCA component at a given weight. 
        function [data] = weightCalc(app, i)
            data = app.Factors(i).slider.Value * app.Signal.weights(app.nChannel, : ,i);
            return
        end
        
        %   plotAll will plot a signal depending on the slider settings with the correct scale. Furthermore it will plot the original signal,
        %   as well as the first 5 weighed (PCA) components.
        function  plotAll(app, data)
            data = squeeze(data);
            %Plot the main signal
            plot(app.UIAxes, data);
            
            hold(app.UIAxes,  'on');
            
            %Plot weighed components, if neccessary.
            if(app.ShowFactorsCheckBox.Value)
                for i = 1:5
                    plot(app.UIAxes, app.weightCalc(i));
                end
                %Plot either the real signal or average, if necessary.
                if(app.SegmentNr.Value && app.ComparetoRealSignalCheckBox.Value)
                    plot(app.UIAxes, squeeze(app.Signal.data(app.nChannel, app.SegmentNr.Value, :)));
                elseif(app.ComparetoRealSignalCheckBox.Value)
                    plot(app.UIAxes, app.PlotSig.av(app.nChannel, :));
                end
            else
                if(app.SegmentNr.Value && app.ComparetoRealSignalCheckBox.Value)
                plot(app.UIAxes, squeeze(app.Signal.data(app.nChannel, app.SegmentNr.Value, :)));
                elseif(app.ComparetoRealSignalCheckBox.Value)
                    plot(app.UIAxes, app.PlotSig.av(app.nChannel, :));
                end
            end
            %Set the Axis Ticks
            if(strcmp(app.Segm.type, "eventRelated"))
                line(app.UIAxes, [app.Segm.lBound app.Segm.lBound], get(app.UIAxes, 'ylim'));
                app.UIAxes.XTick = 0:50:(app.Segm.rBound + app.Segm.lBound);
                app.UIAxes.XTickLabel = (-app.Segm.lBound * 1000/app.Signal.srate):200:(app.Segm.rBound * 1000/app.Signal.srate);
            elseif(strcmp(app.Segm.type, "sequential"))
                app.UIAxes.XTick = 0:50:(app.Segm.width);
                app.UIAxes.XTickLabel = 0:200:(app.Segm.width * 1000/app.Signal.srate);
            end
            hold(app.UIAxes,  'off');
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Startup: Disable interface elemets. We do not have data yet.
            
            app.PCAFactorsPanel.Visible = 'off';
            app.AverageSignalButton.Enable = 'off';
            app.SegmentNr.Enable = 'off';
            app.RandomRealSignalButton.Enable = 'off';
            app.ShowFactorsCheckBox.Enable = 'off';
            app.ComparetoRealSignalCheckBox.Enable = 'off';
            
            %Set the PCA sliders. The default is 14 sliders.
            app.MAXFACT = 14;
            for i = 1:app.MAXFACT
                label = uilabel(app.PCAFactorsPanel);
                slider = uislider(app.PCAFactorsPanel);
                slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
                app.Factors(i).label = label;
                app.Factors(i).slider = slider;
            end
        end

        % Button pushed function: AverageSignalButton
        function AverageSignalButtonPushed(app, event)
            app.SegmentNr.Value = 0;
            app.setPca(app.Signal);
            app.updateUI(app.Signal);
        end

        % Menu selected function: BrowseFile
        function BrowseFileSelected(app, event)
            %Select a file, open a dialogue for segmentation options.
            candidateSignal = pop_loadbv();
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            
            app.BrowseFile.Enable = 'off';
            if(~isempty(candidateSignal))
                app.Signal = candidateSignal;
                app.SegmChoice = SegmentationChoice(app);
            else
                app.BrowseFile.Enable = 'on';
            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.SegmChoice)
            delete(app)
        end

        % Value changed function: ChannelSelect
        function ChannelSelectValueChanged(app, event)
            %Plot the signal of the selected channel and set property to channel number.
            for i = 1:length(app.Signal.channelName)
                if(strcmp(app.ChannelSelect.Value, app.Signal.channelName{i}))
                    app.nChannel = i;
                    app.setPca(app.Signal);
                    break
                end
            end
            app.updateUI(app.Signal);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            %Plot new signal            
            newSig = app.PlotSig.av(app.nChannel, :);
            for i = 1:app.MAXFACT
                newSig = newSig + app.Signal.weights(app.nChannel, :, i) * app.Factors(i).slider.Value;
            end
            app.plotAll(newSig);
        end

        % Value changed function: SegmentNr
        function SegmentNrValueChanged(app, event)
            %Update the user interface and plot the segment.
            app.setPca(app.Signal);
            app.updateUI(app.Signal);
        end

        % Button pushed function: RandomRealSignalButton
        function RandomRealSignalButtonPushed(app, event)
            app.SegmentNr.Value = rand() * app.Signal.nbseg;
            app.setPca(app.Signal);
            app.updateUI(app.Signal);
        end

        % Value changed function: ShowFactorsCheckBox
        function ShowFactorsCheckBoxValueChanged(app, event)
            %Plot the same signal alongside the weighed components.
            newSig = app.PlotSig.av(app.nChannel, :);
            for i = 1:app.MAXFACT
                newSig = newSig + app.Signal.weights(app.nChannel, :, i) * app.Factors(i).slider.Value;
            end
            app.plotAll(newSig);
        end

        % Value changed function: ComparetoRealSignalCheckBox
        function ComparetoRealSignalCheckBoxValueChanged(app, event)
            %Plot the (possibly altered) signal alongside its original.
            newSig = app.PlotSig.av(app.nChannel, :);
            for i = 1:app.MAXFACT
                newSig = newSig + app.Signal.weights(app.nChannel, :, i) * app.Factors(i).slider.Value;
            end
            app.plotAll(newSig);          
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.BrowseFile = uimenu(app.FileMenu);
            app.BrowseFile.MenuSelectedFcn = createCallbackFcn(app, @BrowseFileSelected, true);
            app.BrowseFile.Text = 'Select EEG Signal...';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'EEG Signal')
            xlabel(app.UIAxes, 'Time (ms)')
            ylabel(app.UIAxes, 'Voltage (mV)')
            app.AverageSignalButton = uibutton(app.UIFigure, 'push');
            app.AverageSignalButton.ButtonPushedFcn = createCallbackFcn(app, @AverageSignalButtonPushed, true);
            app.AverageSignalButton.Text = 'Average Signal';
            app.ShowFactorsCheckBox = uicheckbox(app.UIFigure);
            app.ShowFactorsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowFactorsCheckBoxValueChanged, true);
            app.ShowFactorsCheckBox.Text = 'Show Factors';
            app.ComparetoRealSignalCheckBox = uicheckbox(app.UIFigure);
            app.ComparetoRealSignalCheckBox.ValueChangedFcn = createCallbackFcn(app, @ComparetoRealSignalCheckBoxValueChanged, true);
            app.ComparetoRealSignalCheckBox.Text = 'Compare to Real Signal';
            app.DisplaySegmentNrLabel = uilabel(app.UIFigure);
            app.DisplaySegmentNrLabel.Text = 'Display Segment Nr.';
            app.SegmentNr = uieditfield(app.UIFigure, 'numeric');
            app.SegmentNr.RoundFractionalValues = 'on';
            app.SegmentNr.ValueChangedFcn = createCallbackFcn(app, @SegmentNrValueChanged, true);
            app.RandomRealSignalButton = uibutton(app.UIFigure, 'push');
            app.RandomRealSignalButton.ButtonPushedFcn = createCallbackFcn(app, @RandomRealSignalButtonPushed, true);
            app.RandomRealSignalButton.Text = 'Random Real Signal';
            app.PCAFactorsPanel = uipanel(app.UIFigure);
            app.PCAFactorsPanel.Title = 'PCA Factors';
            app.ChannelDropDownLabel = uilabel(app.UIFigure);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelSelect = uidropdown(app.UIFigure);
            app.ChannelSelect.Items = {};
            app.ChannelSelect.ValueChangedFcn = createCallbackFcn(app, @ChannelSelectValueChanged, true);
            app.ChannelSelect.Value = {};
            app.PleaseSelect = uilabel(app.UIFigure);
            app.PleaseSelect.Text = 'Please select an EEG Signal.';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Enable = 'off';
            app.SliderLabel.Visible = 'off';
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Enable = 'off';
            app.Slider.Visible = 'off';
            app.Slider.Value = 50;
        end
    end

    methods (Access = public)

        % Construct app
        function app = SingleEEGPCA1_3

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end